classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AgelessMotionEldoSaverUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        Image4                          matlab.ui.control.Image
        OutputtxtPanel                  matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        TextArea                        matlab.ui.control.TextArea
        RightPanel                      matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        UserInputsPanel                 matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        eg984Label                      matlab.ui.control.Label
        eg72Label                       matlab.ui.control.Label
        eg830Label                      matlab.ui.control.Label
        eg1750Label                     matlab.ui.control.Label
        cmLabel_2                       matlab.ui.control.Label
        yearsLabel                      matlab.ui.control.Label
        kgLabel                         matlab.ui.control.Label
        cmLabel                         matlab.ui.control.Label
        WaistSizeEditField              matlab.ui.control.NumericEditField
        WaistSizeEditFieldLabel         matlab.ui.control.Label
        AgeEditField                    matlab.ui.control.NumericEditField
        AgeEditFieldLabel               matlab.ui.control.Label
        WeightEditField                 matlab.ui.control.NumericEditField
        WeightEditFieldLabel            matlab.ui.control.Label
        HeightEditField                 matlab.ui.control.NumericEditField
        HeightEditFieldLabel            matlab.ui.control.Label
        GridLayout3                     matlab.ui.container.GridLayout
        Button                          matlab.ui.control.Button
        GenerateDesignButton            matlab.ui.control.Button
        ExitButton                      matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        user_data; % struct containing user_data
        output; % output log file data
        output_log_path = "output.txt" % path to output log file
        fileID;
    end
    
    methods (Access = private)
        
         function myUpdateAppLayout(app, ~)
            currentFigureWidth = app.AgelessMotionEldoSaverUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
         end
 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('code'));
            addpath(genpath('media'));
            app.user_data = struct( ...
                        'height', 0, ...
                        'weight', 0, ...
                        'age', 0, ...
                        'waist_circumference', 0);
            app.fileID = fopen(app.output_log_path, 'w');
            app.AgelessMotionEldoSaverUIFigure.SizeChangedFcn = createCallbackFcn(app, @myUpdateAppLayout, true);
            app.TextArea.Value = "[app] Welcome to EldoSaver, by Ageless Motion!";
            log_to_output(app, "[app] See C:/MCG4322b/Group4/README.txt for more info.");
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: HeightEditField
        function HeightEditFieldValueChanged(app, event)
            value = app.HeightEditField.Value;
            app.user_data.height = value;
        end

        % Value changed function: WeightEditField
        function WeightEditFieldValueChanged(app, event)
            value = app.WeightEditField.Value;
            app.user_data.weight = value;
        end

        % Value changed function: AgeEditField
        function AgeEditFieldValueChanged(app, event)
            value = app.AgeEditField.Value;
            app.user_data.age = value;
        end

        % Button pushed function: GenerateDesignButton
        function GenerateDesignButtonPushed(app, event)
            if (~app.user_data.height || ~app.user_data.weight || ~app.user_data.age || ~app.user_data.waist_circumference)
                message = "Make sure to set all four inputs.";
                uialert(app.AgelessMotionEldoSaverUIFigure,message,"Warning", ...
                    "Icon", "warning");
                
            else
                log_to_output(app, "[app] User data received: ");
                log_to_output(app, sprintf("[app]    Height = %2.1f cm", app.user_data.height));
                log_to_output(app, sprintf("[app]    Weight = %4.2f kg", app.user_data.weight));
                log_to_output(app, sprintf("[app]    Age    = %4.2f yrs", app.user_data.age));
                log_to_output(app, sprintf("[app]    Waist  = %2.1f cm", app.user_data.waist_circumference));
                system_design(app);
                log_to_output(app, sprintf("[app] Design generated sucessfully, rebuild in SolidWorks."));
                log_to_output(app, sprintf("[app] Logfile saved to C:/MCG4322b/Group4/output.txt"));
            end
        end

        % Callback function
        function EnterWaistDiaEditFieldValueChanged(app, event)
            value = app.EnterWaistDiaEditField.Value;
            app.user_data.waist_circumference = value;
        end

        % Value changed function: WaistSizeEditField
        function WaistSizeEditFieldValueChanged(app, event)
            value = app.WaistSizeEditField.Value;
            app.user_data.waist_circumference = value;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.TextArea.Value = "";
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.AgelessMotionEldoSaverUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {334, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AgelessMotionEldoSaverUIFigure = uifigure('Visible', 'off');
            app.AgelessMotionEldoSaverUIFigure.Name = 'Ageless Motion - EldoSaver';
            app.AgelessMotionEldoSaverUIFigure.Icon = fullfile(pathToMLAPP, 'media', 'logo.png');
            app.AgelessMotionEldoSaverUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.AgelessMotionEldoSaverUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.LeftPanel);
            app.OutputtxtPanel = uipanel(app.GridLayout5);
            app.OutputtxtPanel.Title = 'Output.txt';
            app.GridLayout6 = uigridlayout(app.OutputtxtPanel);
            app.TextArea = uitextarea(app.GridLayout6);
            app.TextArea.Tag = 'output_log';
            app.TextArea.Editable = 'off';
            app.TextArea.Placeholder = 'Log file will be displayed here!';
            app.Image4 = uiimage(app.GridLayout5);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'media', 'Render1.jpg');
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.ExitButton = uibutton(app.GridLayout3, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.GenerateDesignButton = uibutton(app.GridLayout3, 'push');
            app.GenerateDesignButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateDesignButtonPushed, true);
            app.GenerateDesignButton.Text = 'Generate Design';
            app.Button = uibutton(app.GridLayout3, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'media', 'logo.png');
            app.Button.Text = '';
            app.UserInputsPanel = uipanel(app.GridLayout2);
            app.UserInputsPanel.Title = 'User Inputs';
            app.GridLayout4 = uigridlayout(app.UserInputsPanel);
            app.HeightEditFieldLabel = uilabel(app.GridLayout4);
            app.HeightEditFieldLabel.Text = 'Height:';
            app.HeightEditField = uieditfield(app.GridLayout4, 'numeric');
            app.HeightEditField.Limits = [161.5 189.53];
            app.HeightEditField.ValueDisplayFormat = '%.2f';
            app.HeightEditField.AllowEmpty = 'on';
            app.HeightEditField.ValueChangedFcn = createCallbackFcn(app, @HeightEditFieldValueChanged, true);
            app.HeightEditField.Value = [];
            app.WeightEditFieldLabel = uilabel(app.GridLayout4);
            app.WeightEditFieldLabel.Text = 'Weight:';
            app.WeightEditField = uieditfield(app.GridLayout4, 'numeric');
            app.WeightEditField.Limits = [60.5 119.62];
            app.WeightEditField.ValueDisplayFormat = '%.1f';
            app.WeightEditField.AllowEmpty = 'on';
            app.WeightEditField.ValueChangedFcn = createCallbackFcn(app, @WeightEditFieldValueChanged, true);
            app.WeightEditField.Value = [];
            app.AgeEditFieldLabel = uilabel(app.GridLayout4);
            app.AgeEditFieldLabel.Text = 'Age:';
            app.AgeEditField = uieditfield(app.GridLayout4, 'numeric');
            app.AgeEditField.Limits = [65 90];
            app.AgeEditField.ValueDisplayFormat = '%.0f';
            app.AgeEditField.AllowEmpty = 'on';
            app.AgeEditField.ValueChangedFcn = createCallbackFcn(app, @AgeEditFieldValueChanged, true);
            app.AgeEditField.Value = [];
            app.WaistSizeEditFieldLabel = uilabel(app.GridLayout4);
            app.WaistSizeEditFieldLabel.Text = 'Waist Size:';
            app.WaistSizeEditField = uieditfield(app.GridLayout4, 'numeric');
            app.WaistSizeEditField.Limits = [85.7 133.13];
            app.WaistSizeEditField.ValueDisplayFormat = '%.1f';
            app.WaistSizeEditField.AllowEmpty = 'on';
            app.WaistSizeEditField.ValueChangedFcn = createCallbackFcn(app, @WaistSizeEditFieldValueChanged, true);
            app.WaistSizeEditField.Value = [];
            app.cmLabel = uilabel(app.GridLayout4);
            app.cmLabel.Text = 'cm';
            app.kgLabel = uilabel(app.GridLayout4);
            app.kgLabel.Text = 'kg';
            app.yearsLabel = uilabel(app.GridLayout4);
            app.yearsLabel.Text = 'years';
            app.cmLabel_2 = uilabel(app.GridLayout4);
            app.cmLabel_2.Text = 'cm';
            app.eg1750Label = uilabel(app.GridLayout4);
            app.eg1750Label.Text = 'e.g 175.0';
            app.eg830Label = uilabel(app.GridLayout4);
            app.eg830Label.Text = 'e.g 83.0';
            app.eg72Label = uilabel(app.GridLayout4);
            app.eg72Label.Text = 'e.g 72';
            app.eg984Label = uilabel(app.GridLayout4);
            app.eg984Label.Text = 'e.g 98.4';
            app.Image2 = uiimage(app.GridLayout2);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'media', 'banner.png');
            app.Image3 = uiimage(app.GridLayout2);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'media', 'credits.png');
            % Show the figure after all components are created
            app.AgelessMotionEldoSaverUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AgelessMotionEldoSaverUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AgelessMotionEldoSaverUIFigure)
        end
    end
end